classdef SequenceTask2_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1        matlab.ui.Figure
        shamq          matlab.ui.control.Label
        shamqoptions   matlab.ui.container.ButtonGroup
        NotsureButton  matlab.ui.control.RadioButton
        NoButton       matlab.ui.control.RadioButton
        YesButton      matlab.ui.control.RadioButton
        LH             matlab.ui.control.Image
        enddemo        matlab.ui.control.Label
        i8             matlab.ui.control.Label
        i7             matlab.ui.control.Label
        i6             matlab.ui.control.Label
        i5             matlab.ui.control.Label
        i4             matlab.ui.control.Label
        i3             matlab.ui.control.Label
        i2b            matlab.ui.control.Label
        i2a            matlab.ui.control.Label
        pressspace     matlab.ui.control.Label
        i1             matlab.ui.control.Label
        cross          matlab.ui.control.Label
        stroke4        matlab.ui.control.Label
        key5           matlab.ui.control.Label
        key4           matlab.ui.control.Label
        key3           matlab.ui.control.Label
        key2           matlab.ui.control.Label
        key1           matlab.ui.control.Label
        stroke2        matlab.ui.control.Label
        stroke3        matlab.ui.control.Label
        stroke5        matlab.ui.control.Label
        stroke1        matlab.ui.control.Label
        text23         matlab.ui.control.Label
        uipanel3       matlab.ui.container.Panel
        OtherTxt3      matlab.ui.control.EditField
        Other3         matlab.ui.control.CheckBox
        Burn3          matlab.ui.control.CheckBox
        Itch3          matlab.ui.control.CheckBox
        Tingle3        matlab.ui.control.CheckBox
        PS3            matlab.ui.control.CheckBox
        NoSens3        matlab.ui.control.CheckBox
        text22         matlab.ui.control.Label
        slider3        matlab.ui.control.Slider
        text21         matlab.ui.control.Label
        uipanel2       matlab.ui.container.Panel
        OtherTxt2      matlab.ui.control.EditField
        Other2         matlab.ui.control.CheckBox
        Burn2          matlab.ui.control.CheckBox
        Itch2          matlab.ui.control.CheckBox
        Tingle2        matlab.ui.control.CheckBox
        PS2            matlab.ui.control.CheckBox
        NoSens2        matlab.ui.control.CheckBox
        text20         matlab.ui.control.Label
        slider2        matlab.ui.control.Slider
        text19         matlab.ui.control.Label
        uipanel1       matlab.ui.container.Panel
        OtherTxt1      matlab.ui.control.EditField
        Other1         matlab.ui.control.CheckBox
        Burn1          matlab.ui.control.CheckBox
        Itch1          matlab.ui.control.CheckBox
        Tingle1        matlab.ui.control.CheckBox
        PS1            matlab.ui.control.CheckBox
        NoSens1        matlab.ui.control.CheckBox
        text18         matlab.ui.control.Label
        slider1        matlab.ui.control.Slider
        axes1          matlab.ui.control.UIAxes
    end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%
%   This is the code for the finger tapping task (FTT), originally made in
%   MATLAB GUIDE and migrated to App Designer. Press 'space' at the
%   beginning to open the instructions, which include a task demo. Press
%   'enter' at the beginning, or after the instructions, to start the task.
%   Press 'enter' after the task to open the post-stimulation
%   questionnaire.
%
%   Copyright (c) 2024 Gavin Hsu. All rights reserved.
%   Parra Lab, CCNY, New York, NY
%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


    methods (Access = private)
        %Updates the GUI for the post-stimulation questionnaire
        function pq(app, handles)
            global pqdone
            cla(handles.axes1)
            set(handles.axes1,'Visible','off')
            set(handles.text18,'Visible','on')
            set(handles.text19,'Visible','on')
            set(handles.text20,'Visible','on')
            set(handles.text21,'Visible','on')
            set(handles.text22,'Visible','on')
            set(handles.text23,'Visible','on')
            set(handles.uipanel1,'Visible','on')
            set(handles.uipanel2,'Visible','on')
            set(handles.uipanel3,'Visible','on')
            set(handles.slider1,'Visible','on')
            set(handles.slider2,'Visible','on')
            set(handles.slider3,'Visible','on')
            pqdone = 1;
        end

        %Updates the GUI for page 2 of the post-stimulation questionnaire
        function pq2(app, handles)
            set(handles.text18,'Visible','off')
            set(handles.text19,'Visible','off')
            set(handles.text20,'Visible','off')
            set(handles.text21,'Visible','off')
            set(handles.text22,'Visible','off')
            set(handles.text23,'Visible','off')
            set(handles.uipanel1,'Visible','off')
            set(handles.uipanel2,'Visible','off')
            set(handles.uipanel3,'Visible','off')
            set(handles.slider1,'Visible','off')
            set(handles.slider2,'Visible','off')
            set(handles.slider3,'Visible','off')
            set(app.shamq,'Visible','on')
            set(app.shamqoptions,'Visible','on')
        end
        
        %Updates the GUI during the task
        %Alternates between the active and rest periods every 10 seconds
        function sequence(app, hObject, eventdata, handles)
            global start
            global stop
            global done
            global wait
            global stroke
            global si
            global kp
            global kt
            global tt
            global log
            global seq
            global nck
            global ntrials
            
            for h = 1:length(seq)
                set(handles.(strcat('key',num2str(h))),'string',seq(h))
            end
            tp = 10;    %time per trial (seconds)

            tic
            for i = 1:ntrials
                if stop, break; end
                wait = 1;
                set(si(:),'visible','off')
                set(kp(:),'visible','off')
                set(handles.cross,'visible','on')
                set(handles.axes1,'Visible','on')
                barh(handles.axes1,i-1,'EdgeAlpha',0)
                set(kp,'fontcolor',[0.9412 0.9412 0.9412])
                nck = 0;
                pause(tp)
                
                if stop, break; end
                wait = 0;
                stroke = 0;
                set(kp(:),'visible','on')
                set(handles.cross,'visible','off')
                cla(handles.axes1)
                set(handles.axes1,'Visible','off')
                pause(tp)
                log.(strcat('trial',num2str(i))) = [kt,tt];
                kt = [];
                tt = [];
            end
            
            start = 0;
            done = 1;
            toc
            if stop, return
            else, set(handles.cross,'visible','off'); end
            set(si(:),'visible','off')
            set(kp(:),'visible','off')
        end
        
        %Updates the GUI for the task demo
        function taskdemo(app, hObject, eventdata, handles)
            global kp
            global si
            global stroke
            global ip

            set(app.i4,'visible','off')
            set(app.pressspace,'visible','off')
            set(app.i5,'visible','on')

            demoseq = ["2";"1";"4";"3";"1"];         
            for h = 1:length(demoseq)
                set(handles.(strcat('key',num2str(h))),'string',demoseq(h))
            end
            set(si(:),'visible','off')
            set(kp(:),'visible','on')
            stroke = 0;
            set(kp(:),'visible','on')
            ip = 5;
        end
        
        %The functions below update the GUI for the task instructions

        function instructions1(app,hObject,eventdata,handles)
            global ip
            set(app.i1,'visible','on')
            set(app.LH,'visible','on')
            pause(5)
            set(app.pressspace,'visible','on')
            ip = 1;
        end

        function instructions2(app,hObject,eventdata,handles)
            global ip
            global kp
            set(app.i1,'visible','off')
            set(app.LH,'visible','off')
            set(app.pressspace,'visible','off')
            set(app.i2a,'visible','on')
            set(app.i2b,'visible','on')
            demoseq = ["2";"1";"4";"3";"1"];         
            for h = 1:length(demoseq)
                set(handles.(strcat('key',num2str(h))),'string',demoseq(h))
            end
            set(kp(:),'visible','on')
            pause(5)
            set(app.pressspace,'visible','on')
            ip = 2;
        end

        function instructions3(app,hObject,eventdata,handles)
            global ip
            global si
            set(app.i2a,'visible','off')
            set(app.i2b,'visible','off')
            set(app.pressspace,'visible','off')
            set(app.i3,'visible','on')
            set(si(1:2),'visible','on')
            pause(5)
            set(app.pressspace,'visible','on')
            ip = 3;
        end

        function instructions4(app,hObject,eventdata,handles)
            global ip
            set(app.i3,'visible','off')
            set(app.i4,'visible','on')
            set(app.pressspace,'visible','off')
            pause(5)
            set(app.pressspace,'visible','on')
            ip = 4;
        end

        function instructions6(app,hObject,eventdata,handles)
            global kp
            global si
            global ip
            set(kp(:),'visible','off')
            set(si(:),'visible','off')
            set(app.i5,'visible','off')
            set(app.i6,'visible','on')
            set(app.pressspace,'visible','off')
            pause(5)
            set(app.pressspace,'visible','on')
            ip = 6;
        end

        function instructions7(app,hObject,eventdata,handles)
            global ip
            set(app.i6,'visible','off')
            set(app.i7,'visible','on')
            set(app.pressspace,'visible','off')
            pause(5)
            set(app.pressspace,'visible','on')
            ip = 7;
        end

        function instructions8(app,hObject,eventdata,handles)
            global ip
            set(app.i7,'visible','off')
            set(app.i8,'visible','on')
            set(app.cross,'visible','on')
            set(app.pressspace,'visible','off')
            pause(5)
            set(app.pressspace,'visible','on')
            ip = 8;
        end

        function clearGUI(app,hObject,eventdata,handles)
            set(app.i8,'visible','off')
            set(app.cross,'visible','off')
            set(app.pressspace,'visible','off')
            set(app.enddemo,'visible','on')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function SequenceTaskDemo_OpeningFcn(app, filename, sn, session)
            % Ensure that the app appears on screen when run
%             movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % Choose default command line output for SequenceTaskDemo
            handles.output = hObject;
            clear global
            global start
            global stop
            global done
            global stroke
            global si %stroke indicators
            global kp %key prompts
            global wait
            global m
            global seq
            global quality
            global rating
            global otherStr
            global keyseq
            global ncs
            global nck
            global demokeyseq
            global ip
            global ndemo
            global sham
            global pqdone
            global ntrials
            
            start = 0;  %Used to check whether the task has started
            stop = 0;   %Used to check whether the task has been stopped prematurely
            done = 0;   %Used to check whether the task has finished
            stroke = 0; %Current number of keystrokes (0 to 5)
            wait = 1;   %Set to 1 during the rest period and 0 during the active period
            ncs = 0;    %Number of correct sequences within a trial
            nck = 0;    %Number of correct keys within a 5-key sequence
            demokeyseq = 'bvmnv'; %21431 is the demo sequence
            ip = 0;     %Instruction page
            ndemo = 0;  %Number of correct sequences during demo
            pqdone = 0; %Whether the post-stimulation questionnaire has been completed
            ntrials = 36;   %Number of trials

            m = matfile(filename,'Writable',true);  %Output file
            
            if strcmp(sn,'1')
                seq = ["4";"1";"3";"2";"4"];    %Sequence S1 (LH) from the literature
                keyseq = 'mvnbm';
            elseif strcmp(sn,'2')
                seq = ["2";"3";"1";"4";"2"];    %Sequence S2 (RH)
                keyseq = 'bnvmb';
            elseif strcmp(sn,'3')
                seq = ["3";"4";"2";"1";"3"];    %Sequence S3 (LH)
                keyseq = 'nmbvn';
            end
            
            m.sequence = seq;
            m.session = session;
            m.sn = sn;
            
            si = [handles.stroke1,handles.stroke2,handles.stroke3,handles.stroke4,handles.stroke5]; %Stroke indicators for key presses
            kp = [app.key1,app.key2,app.key3,app.key4,app.key5];    %Key labels

            set(handles.axes1,'XLim',[0,ntrials])
            hold on
            
            quality = zeros(3,6);
            rating = zeros(3,1);
            otherStr = strings([3,1]);
            sham = [];
            movegui(app.figure1, 'center');
            set(app.figure1,'WindowState','normal')
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: Other1
        function Other1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global quality
            quality(1,6) = get(hObject,'Value');
            if quality(1,6) == 1
                set(handles.OtherTxt1,'Visible','on');
            else
                set(handles.OtherTxt1,'Visible','off');
            end
        end

        % Value changed function: Other2
        function Other2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global quality
            quality(2,6) = get(hObject,'Value');
            if quality(2,6) == 1
                set(handles.OtherTxt2,'Visible','on');
            else
                set(handles.OtherTxt2,'Visible','off');
            end
        end

        % Value changed function: Other3
        function Other3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global quality
            quality(3,6) = get(hObject,'Value');
            if quality(3,6) == 1
                set(handles.OtherTxt3,'Visible','on');
            else
                set(handles.OtherTxt3,'Visible','off');
            end
        end

        % Value changed function: OtherTxt1
        function OtherTxt1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global otherStr
            otherStr(1) = get(hObject,'String');
        end

        % Value changed function: OtherTxt2
        function OtherTxt2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global otherStr
            otherStr(2) = get(hObject,'String');
        end

        % Value changed function: OtherTxt3
        function OtherTxt3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global otherStr
            otherStr(3) = get(hObject,'String');
        end

        % Close request function: figure1
        function figure1_CloseRequestFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %All data are recorded and saved when the GUI is closed.
            
            global stop
            global log
            global quality
            global rating
            global otherStr
            global m
            global sham
            stop = 1;
            
            %Store post questionnaire responses
            rating(1) = get(handles.slider1,'Value');
            rating(2) = get(handles.slider2,'Value');
            rating(3) = get(handles.slider3,'Value');
            quality(1,1) = get(handles.NoSens1,'Value');
            quality(2,1) = get(handles.NoSens2,'Value');
            quality(3,1) = get(handles.NoSens3,'Value');
            quality(1,2) = get(handles.Tingle1,'Value');
            quality(2,2) = get(handles.Tingle2,'Value');
            quality(3,2) = get(handles.Tingle3,'Value');
            quality(1,3) = get(handles.PS1,'Value');
            quality(2,3) = get(handles.PS2,'Value');
            quality(3,3) = get(handles.PS3,'Value');
            quality(1,4) = get(handles.Itch1,'Value');
            quality(2,4) = get(handles.Itch2,'Value');
            quality(3,4) = get(handles.Itch3,'Value');
            quality(1,5) = get(handles.Burn1,'Value');
            quality(2,5) = get(handles.Burn2,'Value');
            quality(3,5) = get(handles.Burn3,'Value');
            otherStr(1) = get(handles.OtherTxt1,'string');
            otherStr(2) = get(handles.OtherTxt2,'string');
            otherStr(3) = get(handles.OtherTxt3,'string');
            
            m.log = log;
            m.quality = quality;
            m.rating = rating;
            m.otherStr = otherStr;
            m.sham = sham;
            delete(hObject);
        end

        % Key press function: figure1
        function figure1_KeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %This function controls the GUI behavior in response to key
            %inputs throughout the different sections of the program.

            global start
            global done
            global kt
            global tt
            global stroke
            global kp
            global si
            global wait
            global ncs
            global nck
            global demokeyseq
            global ip
            global ndemo
            global pqdone

            %Press the 'enter' key to start the task
            if strcmp(eventdata.Key,'return') && ~start && ~done
                start = 1;
                set(app.enddemo,'visible','off')
                set(handles.cross,'visible','on')
                set(si(:),'visible','off')
                set(kp(:),'visible','off')
                sequence(app, hObject, eventdata, handles)

            %When showing the task for the first time, press 'space' at the
            %beginning to show the instructions. Continue pressing 'space'
            %to continue through the instructions.
            elseif strcmp(eventdata.Key,'space') && ~start && ~done
                if ip == 0
                    instructions1(app, hObject,eventdata,handles)
                elseif ip == 1
                    instructions2(app, hObject,eventdata,handles)
                elseif ip == 2
                    instructions3(app, hObject,eventdata,handles)
                elseif ip == 3
                    instructions4(app, hObject,eventdata,handles)
                elseif ip == 4
                    taskdemo(app, hObject,eventdata,handles)
                elseif ip == 6
                    instructions7(app, hObject,eventdata,handles)
                elseif ip == 7
                    instructions8(app, hObject,eventdata,handles)
                elseif ip == 8
                    clearGUI(app, hObject,eventdata,handles)
                end

            %During the instructions, the subject gets an opportunity to
            %practice a demo version of the task. The numbers turn green to
            %indicate a correct input and red to indicate an error.
            elseif ~strcmp(eventdata.Key,'return') && ~strcmp(eventdata.Key,'space') && ~start && ~done && ip == 5 && ndemo < 3
                stroke = stroke + 1;
                if stroke == 1
                    set(kp,'fontcolor',[0.9412 0.9412 0.9412])
                    if strcmp(eventdata.Key,demokeyseq(stroke))
                        set(kp(stroke),'FontColor',[0,1,0])
                    else
                        set(kp(stroke),'FontColor',[1,0,0])
                    end
                    set(si(stroke),'visible','on')
                elseif stroke == 5
                    if strcmp(eventdata.Key,demokeyseq(stroke))
                        set(kp(stroke),'FontColor',[0,1,0])
                    else
                        set(kp(stroke),'FontColor',[1,0,0])
                    end
                    set(si(stroke),'visible','on')
                    pause(0.1)
                    stroke = 0;
                    set(si(:),'visible','off')
                    ndemo = ndemo + 1;
                    if ndemo == 3
                        instructions6(app, hObject,eventdata,handles)
                    end
                elseif stroke > 1 && stroke < 5
                    if strcmp(eventdata.Key,demokeyseq(stroke))
                        set(kp(stroke),'FontColor',[0,1,0])
                    else
                        set(kp(stroke),'FontColor',[1,0,0])
                    end
                    set(si(stroke),'visible','on')
                end

            %During the task, asterisks appear above the numbers as keys
            %are pressed. They disappear and cycle every 5 presses.
            elseif ~strcmp(eventdata.Key,'return') && ~done && ~wait
                t = toc;
                kt = [kt;string(eventdata.Key)];
                tt = [tt;t];
                stroke = stroke + 1;
                if stroke == 1
                    set(kp,'fontcolor',[0.9412 0.9412 0.9412])
                    set(si(stroke),'visible','on')
                elseif stroke == 5
                    set(si(stroke),'visible','on')
                    pause(0.1)
                    if nck == 5
                        ncs = ncs + 1;
                    end
                    stroke = 0;
                    nck = 0;
                    set(si(:),'visible','off')
                elseif stroke > 1 && stroke < 5
                    set(si(stroke),'visible','on')
                end

            %Press 'enter' after the end of the task to open the
            %post-stimulation questionnaire. Press 'enter' again to go to
            %the second page of the questionnaire.
            elseif strcmp(eventdata.Key,'return') && done && ~start && ~pqdone
                pq(app, handles)
            elseif strcmp(eventdata.Key,'return') && done && ~start && pqdone
                pq2(app, handles)
            end
        end

        % Selection changed function: shamqoptions
        function shamqoptionsSelectionChanged(app, event)
            global sham
            selectedButton = app.shamqoptions.SelectedObject;
            switch selectedButton.Text
                case 'Yes'
                    sham = 'Yes';
                case 'No'
                    sham = 'No';
                case 'Not Sure'
                    sham = 'Not sure';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'SequenceTask2';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1_CloseRequestFcn, true);
            app.figure1.KeyPressFcn = createCallbackFcn(app, @figure1_KeyPressFcn, true);
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.XLim = [0 36];
            app.axes1.XTick = [0 36];
            app.axes1.XTickLabel = '';
            app.axes1.YTick = [0 0.5 1];
            app.axes1.YTickLabel = {'0'; '0.5'; '1'};
            app.axes1.Tag = 'axes1';
            app.axes1.Visible = 'off';
            app.slider1 = uislider(app.figure1);
            app.slider1.Limits = [0 10];
            app.slider1.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.slider1.MinorTicks = [];
            app.slider1.Tag = 'slider1';
            app.slider1.Visible = 'off';
            app.text18 = uilabel(app.figure1);
            app.text18.Tag = 'text18';
            app.text18.Visible = 'off';
            app.text18.Text = 'Please describe the sensation at the beginning of the stimulation (check all that apply)';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Visible = 'off';
            app.uipanel1.Tag = 'uipanel1';
            app.NoSens1 = uicheckbox(app.uipanel1);
            app.NoSens1.Tag = 'NoSens1';
            app.NoSens1.Text = 'No sensation';
            app.PS1 = uicheckbox(app.uipanel1);
            app.PS1.Tag = 'PS1';
            app.PS1.Text = 'Pricking/Stinging';
            app.Tingle1 = uicheckbox(app.uipanel1);
            app.Tingle1.Tag = 'Tingle1';
            app.Tingle1.Text = 'Tingling';
            app.Itch1 = uicheckbox(app.uipanel1);
            app.Itch1.Tag = 'Itch1';
            app.Itch1.Text = 'Itching';
            app.Burn1 = uicheckbox(app.uipanel1);
            app.Burn1.Tag = 'Burn1';
            app.Burn1.Text = 'Burning';
            app.Other1 = uicheckbox(app.uipanel1);
            app.Other1.ValueChangedFcn = createCallbackFcn(app, @Other1_Callback, true);
            app.Other1.Tag = 'Other1';
            app.Other1.Text = 'Other';
            app.OtherTxt1 = uieditfield(app.uipanel1, 'text');
            app.OtherTxt1.ValueChangedFcn = createCallbackFcn(app, @OtherTxt1_Callback, true);
            app.OtherTxt1.Tag = 'OtherTxt1';
            app.OtherTxt1.Visible = 'off';
            app.text19 = uilabel(app.figure1);
            app.text19.Tag = 'text19';
            app.text19.Visible = 'off';
            app.text19.Text = 'Please rate the sensation level at the beginning of the stimulation';
            app.slider2 = uislider(app.figure1);
            app.slider2.Limits = [0 10];
            app.slider2.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.slider2.MinorTicks = [];
            app.slider2.Tag = 'slider2';
            app.slider2.Visible = 'off';
            app.text20 = uilabel(app.figure1);
            app.text20.Tag = 'text20';
            app.text20.Visible = 'off';
            app.text20.Text = 'Please describe the sensation during the stimulation (check all that apply)';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Visible = 'off';
            app.uipanel2.Tag = 'uipanel2';
            app.NoSens2 = uicheckbox(app.uipanel2);
            app.NoSens2.Tag = 'NoSens2';
            app.NoSens2.Text = 'No sensation';
            app.PS2 = uicheckbox(app.uipanel2);
            app.PS2.Tag = 'PS2';
            app.PS2.Text = 'Pricking/Stinging';
            app.Tingle2 = uicheckbox(app.uipanel2);
            app.Tingle2.Tag = 'Tingle2';
            app.Tingle2.Text = 'Tingling';
            app.Itch2 = uicheckbox(app.uipanel2);
            app.Itch2.Tag = 'Itch2';
            app.Itch2.Text = 'Itching';
            app.Burn2 = uicheckbox(app.uipanel2);
            app.Burn2.Tag = 'Burn2';
            app.Burn2.Text = 'Burning';
            app.Other2 = uicheckbox(app.uipanel2);
            app.Other2.ValueChangedFcn = createCallbackFcn(app, @Other2_Callback, true);
            app.Other2.Tag = 'Other2';
            app.Other2.Text = 'Other';
            app.OtherTxt2 = uieditfield(app.uipanel2, 'text');
            app.OtherTxt2.ValueChangedFcn = createCallbackFcn(app, @OtherTxt2_Callback, true);
            app.OtherTxt2.Tag = 'OtherTxt2';
            app.OtherTxt2.Visible = 'off';
            app.text21 = uilabel(app.figure1);
            app.text21.Tag = 'text21';
            app.text21.Visible = 'off';
            app.text21.Text = 'Please rate the sensation level during the stimulation';
            app.slider3 = uislider(app.figure1);
            app.slider3.Limits = [0 10];
            app.slider3.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.slider3.MinorTicks = [];
            app.slider3.Tag = 'slider3';
            app.slider3.Visible = 'off';
            app.text22 = uilabel(app.figure1);
            app.text22.Tag = 'text22';
            app.text22.Visible = 'off';
            app.text22.Text = 'Please describe the sensation after the stimulation (check all that apply)';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Visible = 'off';
            app.uipanel3.Tag = 'uipanel3';
            app.NoSens3 = uicheckbox(app.uipanel3);
            app.NoSens3.Tag = 'NoSens3';
            app.NoSens3.Text = 'No sensation';
            app.PS3 = uicheckbox(app.uipanel3);
            app.PS3.Tag = 'PS3';
            app.PS3.Text = 'Pricking/Stinging';
            app.Tingle3 = uicheckbox(app.uipanel3);
            app.Tingle3.Tag = 'Tingle3';
            app.Tingle3.Text = 'Tingling';
            app.Itch3 = uicheckbox(app.uipanel3);
            app.Itch3.Tag = 'Itch3';
            app.Itch3.Text = 'Itching';
            app.Burn3 = uicheckbox(app.uipanel3);
            app.Burn3.Tag = 'Burn3';
            app.Burn3.Text = 'Burning';
            app.Other3 = uicheckbox(app.uipanel3);
            app.Other3.ValueChangedFcn = createCallbackFcn(app, @Other3_Callback, true);
            app.Other3.Tag = 'Other3';
            app.Other3.Text = 'Other';
            app.OtherTxt3 = uieditfield(app.uipanel3, 'text');
            app.OtherTxt3.ValueChangedFcn = createCallbackFcn(app, @OtherTxt3_Callback, true);
            app.OtherTxt3.Tag = 'OtherTxt3';
            app.OtherTxt3.Visible = 'off';
            app.text23 = uilabel(app.figure1);
            app.text23.Tag = 'text23';
            app.text23.Visible = 'off';
            app.text23.Text = 'Please rate the sensation level after the stimulation';
            app.stroke1 = uilabel(app.figure1);
            app.stroke1.Tag = 'stroke1';
            app.stroke1.Visible = 'off';
            app.stroke1.Text = '*';
            app.stroke5 = uilabel(app.figure1);
            app.stroke5.Tag = 'stroke5';
            app.stroke5.Visible = 'off';
            app.stroke5.Text = '*';
            app.stroke3 = uilabel(app.figure1);
            app.stroke3.Tag = 'stroke3';
            app.stroke3.Visible = 'off';
            app.stroke3.Text = '*';
            app.stroke2 = uilabel(app.figure1);
            app.stroke2.Tag = 'stroke2';
            app.stroke2.Visible = 'off';
            app.stroke2.Text = '*';
            app.key1 = uilabel(app.figure1);
            app.key1.Tag = 'key1';
            app.key1.Visible = 'off';
            app.key1.Text = '1';
            app.key2 = uilabel(app.figure1);
            app.key2.Tag = 'key2';
            app.key2.Visible = 'off';
            app.key2.Text = '2';
            app.key3 = uilabel(app.figure1);
            app.key3.Tag = 'key3';
            app.key3.Visible = 'off';
            app.key3.Text = '3';
            app.key4 = uilabel(app.figure1);
            app.key4.Tag = 'key4';
            app.key4.Visible = 'off';
            app.key4.Text = '4';
            app.key5 = uilabel(app.figure1);
            app.key5.Tag = 'key5';
            app.key5.Visible = 'off';
            app.key5.Text = '5';
            app.stroke4 = uilabel(app.figure1);
            app.stroke4.Tag = 'stroke4';
            app.stroke4.Visible = 'off';
            app.stroke4.Text = '*';
            app.cross = uilabel(app.figure1);
            app.cross.Tag = 'cross';
            app.cross.Visible = 'off';
            app.cross.Text = '+';
            app.i1 = uilabel(app.figure1);
            app.i1.Visible = 'off';
            app.i1.Text = {'During this task you will repeatedly press keys in a sequence.'; ''; 'Place your LEFT hand fingers over the keys on the keypad as follows:'; ''; '- little finger on 1'; '- ring finger on 2'; '- middle finger on 3'; '- index finger on 4'; ''; 'Keep your fingers in these positions throughout the task and do not move them to different keys.'};
            app.pressspace = uilabel(app.figure1);
            app.pressspace.Visible = 'off';
            app.pressspace.Text = 'Press ''space'' to continue';
            app.i2a = uilabel(app.figure1);
            app.i2a.Visible = 'off';
            app.i2a.Text = {'During the task, you will see a sequence of 5 numbers appear on the screen as shown below.'; ''; 'Whenever the numbers are on the screen, press the corresponding keys in the order of the sequence. '};
            app.i2b = uilabel(app.figure1);
            app.i2b.Visible = 'off';
            app.i2b.Text = 'In this example you would first press 2, followed by 1, then 4, then 3, and 1.';
            app.i3 = uilabel(app.figure1);
            app.i3.Visible = 'off';
            app.i3.Text = {'Every time you press a key, a marker will appear above the numbers in order from left to right. '; ''; 'For example, it looks like this when you have pressed two keys:'};
            app.i4 = uilabel(app.figure1);
            app.i4.Visible = 'off';
            app.i4.Text = {'When you have pressed 5 keys, the markers will reset and you may start over.'; ''; 'Repeat until time runs out.'};
            app.i5 = uilabel(app.figure1);
            app.i5.Visible = 'off';
            app.i5.Text = {'Let''s try it a few times. Press the sequence 3 times to continue.'; ''; 'You must use all fingers on your LEFT hand.'};
            app.i6 = uilabel(app.figure1);
            app.i6.Visible = 'off';
            app.i6.Text = {'A marker will appear every time you press any key, even if it is an incorrect key. '; ''; 'During the actual task you will not be told if you make a mistake, but try to be as accurate as possible.'};
            app.i7 = uilabel(app.figure1);
            app.i7.Visible = 'off';
            app.i7.Text = {'There will be 36 trials during this task. During each trial you will have 10 seconds to press the keys. '; ''; 'While the numbers are on the screen, repeat the sequence over and over again until time is up. '; ''; 'Press the keys as quickly and accurately as possible!'};
            app.i8 = uilabel(app.figure1);
            app.i8.Visible = 'off';
            app.i8.Text = {'At the end of each trial, there will be a 10-second break and you will see a cross on the screen, as shown here. '; ''; 'During the break, stare at the cross and do not move your fingers until the next trial begins automatically.'};
            app.enddemo = uilabel(app.figure1);
            app.enddemo.Visible = 'off';
            app.enddemo.Text = {'You have reached the end of the instructions.'; ''; 'Please silence your phone and put it away.'};
            app.LH = uiimage(app.figure1);
            app.LH.Visible = 'off';
            app.LH.ImageSource = fullfile(pathToMLAPP, 'LH.png');
            app.shamqoptions = uibuttongroup(app.figure1);
            app.shamqoptions.SelectionChangedFcn = createCallbackFcn(app, @shamqoptionsSelectionChanged, true);
            app.shamqoptions.Visible = 'off';
            app.YesButton = uiradiobutton(app.shamqoptions);
            app.YesButton.Text = 'Yes';
            app.NoButton = uiradiobutton(app.shamqoptions);
            app.NoButton.Text = 'No';
            app.NotsureButton = uiradiobutton(app.shamqoptions);
            app.NotsureButton.Text = 'Not sure';
            app.NotsureButton.Value = true;
            app.shamq = uilabel(app.figure1);
            app.shamq.Visible = 'off';
            app.shamq.Text = 'Do you think you received active stimulation?';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SequenceTask2_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)SequenceTaskDemo_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end